classdef encryption < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EncryptionUIFigure   matlab.ui.Figure
        InputEditField       matlab.ui.control.EditField
        InputEditFieldLabel  matlab.ui.control.Label
        EncryptButton        matlab.ui.control.Button
    end

    
    methods (Access = private)

        function startEncryption(app)
            text = app.InputEditField.Value;
            if ~isletter(text)
                msgbox("Invalid Value","Error","error");
            else
                [c, k] = encrypt(text);
                save("cipher.mat","c","k");
                key = '';
                for i = 1 : size(k, 1)
                    for j = 1 : size(k, 2)
                        key = [key sprintf('%d',k(i,j))];
                        key = [key ' '];
                    end
                    key = [key newline];
                end

                msgbox(sprintf('Cipher: %s%cKey:%c%s', c, newline, newline, key),'Success')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Close request function: EncryptionUIFigure
        function EncryptionUIFigureCloseRequest(app, event)
            main;
            delete(app)
        end

        % Button pushed function: EncryptButton
        function EncryptButtonPushed(app, event)
            startEncryption(app);
        end

        % Value changing function: InputEditField
        function InputEditFieldValueChanging(app, event)
            app.InputEditField.FontColor = [0.0, 0.0, 0.0];
            changingValue = event.Value;
            if strcmpi(changingValue, '')
                app.InputEditField.FontColor = app.defaultColor;
            end
        end

        % Value changed function: InputEditField
        function InputEditFieldValueChanged(app, event)
            startEncryption(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EncryptionUIFigure = uifigure('Visible', 'off');
            app.EncryptionUIFigure.Name = 'Encryption';
            app.EncryptionUIFigure.CloseRequestFcn = createCallbackFcn(app, @EncryptionUIFigureCloseRequest, true);
            app.EncryptButton = uibutton(app.EncryptionUIFigure, 'push');
            app.EncryptButton.ButtonPushedFcn = createCallbackFcn(app, @EncryptButtonPushed, true);
            app.EncryptButton.Text = 'Encrypt';
            app.InputEditFieldLabel = uilabel(app.EncryptionUIFigure);
            app.InputEditFieldLabel.Text = 'Input';
            app.InputEditField = uieditfield(app.EncryptionUIFigure, 'text');
            app.InputEditField.ValueChangedFcn = createCallbackFcn(app, @InputEditFieldValueChanged, true);
            app.InputEditField.ValueChangingFcn = createCallbackFcn(app, @InputEditFieldValueChanging, true);
            % Show the figure after all components are created
            app.EncryptionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = encryption

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EncryptionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EncryptionUIFigure)
        end
    end
end